classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image_4                     matlab.ui.control.Image
        Image_3                     matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        Label                       matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        GiveMeSuggestionsButton     matlab.ui.control.Button
        AnalyzeDataButton           matlab.ui.control.Button
        OrganizeDataButton          matlab.ui.control.Button
        ImportHistoricalDataButton  matlab.ui.control.Button
        uBestEverMegaMillionsNumberGeneratoruLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        a = [];
        b = [];
        c = figure;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.Visible = "off";
            app.Image_2.Visible = "off";
            app.Image_3.Visible = "off";
            app.Image_4.Visible = "off";
        end

        % Button pushed function: ImportHistoricalDataButton
        function ImportHistoricalDataButtonPushed(app, event)
            app.Image.Visible = "on";
            drawnow;
            a_site_scraper;
            app.Image.Visible = "off";
            app.Label_6.Text = "Done!";
            drawnow;
        end

        % Button pushed function: OrganizeDataButton
        function OrganizeDataButtonPushed(app, event)
            app.Image_2.Visible = "on";
            drawnow;
            b_data_organizer;
            app.Image_2.Visible = "off";
            app.Label_3.Text = "Done!";
            drawnow;
        end

        % Button pushed function: AnalyzeDataButton
        function AnalyzeDataButtonPushed(app, event)
            app.Image_3.Visible = "on";
            drawnow;
            [app.a, app.b, app.c] = c_data_analyzer();
            app.Image_3.Visible = "off";
            app.Label_4.Text = "Done!";
            drawnow;
        end

        % Button pushed function: GiveMeSuggestionsButton
        function GiveMeSuggestionsButtonPushed(app, event)
            prompt = {"I have two methods of picking lottery numbers, " + ...
                "and I will do each for you. How many sets of lottery " + ...
                "picks do you want?"};
            dlgtitle = "How many suggestions should I generate?";
            fieldsize = [1 65];
            answer = inputdlg(prompt,dlgtitle,fieldsize);
            if length(answer) > 0 && str2double(cell2mat(answer(1))) >= 1
                app.Image_4.Visible = "on";
                drawnow;
                for q = 1:str2double(cell2mat(answer(1)))
                    d_make_recommendation(app.a, app.b, app.c, q);
                end
                app.Image_4.Visible = "off";
                app.Label_5.Text = "Done!";
                drawnow;
    
                winopen(".\Your Recommendations\");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.uBestEverMegaMillionsNumberGeneratoruLabel = uilabel(app.UIFigure);
            app.uBestEverMegaMillionsNumberGeneratoruLabel.Interpreter = 'html';
            app.uBestEverMegaMillionsNumberGeneratoruLabel.Text = '<u> Best Ever Mega Millions Number Generator* </u>';
            app.ImportHistoricalDataButton = uibutton(app.UIFigure, 'push');
            app.ImportHistoricalDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportHistoricalDataButtonPushed, true);
            app.ImportHistoricalDataButton.Text = 'Import Historical Data';
            app.OrganizeDataButton = uibutton(app.UIFigure, 'push');
            app.OrganizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @OrganizeDataButtonPushed, true);
            app.OrganizeDataButton.Text = 'Organize Data';
            app.AnalyzeDataButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeDataButtonPushed, true);
            app.AnalyzeDataButton.Text = 'Analyze Data';
            app.GiveMeSuggestionsButton = uibutton(app.UIFigure, 'push');
            app.GiveMeSuggestionsButton.ButtonPushedFcn = createCallbackFcn(app, @GiveMeSuggestionsButtonPushed, true);
            app.GiveMeSuggestionsButton.Text = 'Give Me Suggestions';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Tag = 'Label_6';
            app.Label_6.Text = ' ';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = ' ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = ' ';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = ' ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '* = odds are better than going to the cashier and asking for a random set of numbers.';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'resources', 'hzk6C.gif');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'resources', 'hzk6C.gif');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'resources', 'hzk6C.gif');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'resources', 'hzk6C.gif');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end